classdef NguyenPhuocBao_21207123_bai_tap_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image                 matlab.ui.control.Image
        Image2                matlab.ui.control.Image
        title                 matlab.ui.control.Label
        idstudent             matlab.ui.control.Label
        but8                  matlab.ui.control.Button
        but9                  matlab.ui.control.Button
        but10                 matlab.ui.control.Button
        but1112               matlab.ui.control.Button
        input1                matlab.ui.control.EditField
        input2                matlab.ui.control.EditField
        label1                matlab.ui.control.Label
        label2                matlab.ui.control.Label
        phanlya               matlab.ui.control.EditField
        phanlyb               matlab.ui.control.EditField
        saiso                 matlab.ui.control.EditField
        labela                matlab.ui.control.Label
        labelb                matlab.ui.control.Label
        labelss               matlab.ui.control.Label
        PHNGPHPDropDownLabel  matlab.ui.control.Label
        PP                    matlab.ui.control.DropDown
        UIAxes                matlab.ui.control.UIAxes
        solanlap              matlab.ui.control.EditField
        labelsll              matlab.ui.control.Label
        labelkq               matlab.ui.control.Label
        KQ                    matlab.ui.control.EditField
        labelkq2              matlab.ui.control.Label
        KQ2                   matlab.ui.control.EditField
        RUNButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: but8
        function but8ButtonPushed(app, event)
            app.but8.Text = '3.2(bai so 2)/Tr.103';
            app.but9.Text = 'Chương 9';
            app.but10.Text = 'Chương 10';
            app.but1112.Text = 'Chương 11&12';
            app.PP.Items = {'Dây cung', 'Tiếp tuyến', 'Lặp đơn'};
            app.input1.Value = 'x^3 - 2*x^2 - 4*x + 7';
            app.input2.Value = '(x^3)/4 - (x^2)/2 + 7/4';
            app.phanlya.Value = '1';
            app.phanlyb.Value = '2';
            app.saiso.Value = '0.01';
            app.label1.Visible = 'on';
            app.label2.Visible = 'on';
            app.labela.Visible = 'on';
            app.labelb.Visible = 'on';
            app.labelsll.Visible = 'on';
            app.input1.Visible = 'on';
            app.input2.Visible = 'on';
            app.phanlya.Visible = 'on';
            app.phanlyb.Visible = 'on';
            app.saiso.Visible = 'on';
            app.labelkq.Visible = 'on';
            app.labelsll.Visible = 'on';
            app.KQ.Visible = 'on';
            app.solanlap.Visible = 'on';
            app.UIAxes.Visible = 'on';
            app.labelkq2.Visible = 'off';
            app.KQ2.Visible = 'off';
            cla(app.UIAxes, 'reset');
        end

        % Button pushed function: but9
        function but9ButtonPushed(app, event)
            app.but9.Text = '4.2(bai so 1)/Tr.158';
            app.but8.Text = 'Chương 8';
            app.but10.Text = 'Chương 10';
            app.but1112.Text = 'Chương 11&12';
            app.PP.Items = {'GaussJordan'};
            app.input1.Value ='[1, 1, -2, 1; 1, -3, 1, 1; 4, -1, -1, -1; 4, 3, -4, -1]';
            app.input2.Value = '[1; 0; 1; 2]';
            app.label1.Visible = 'on';
            app.label2.Visible = 'on';
            app.input1.Visible = 'on';
            app.input2.Visible = 'on';
            app.labelkq2.Visible = 'off';
            app.KQ2.Visible = 'off';
            app.phanlya.Visible = 'off';
            app.phanlyb.Visible = 'off';
            app.saiso.Visible = 'off';
            app.labelkq.Visible = 'on';
            app.labelsll.Visible = 'off';
            app.labelb.Visible = 'off';
            app.labela.Visible = 'off';
            app.labelss.Visible = 'off';
            app.KQ.Visible = 'on';
            app.solanlap.Visible = 'off';
            app.UIAxes.Visible = 'on';
            cla(app.UIAxes, 'reset');
        end

        % Button pushed function: but10
        function but10ButtonPushed(app, event)
            app.but10.Text = '5.3/Tr.189';
            app.but8.Text = 'Chương 8';
            app.but9.Text = 'Chương 9';
            app.but1112.Text = 'Chương 11&12';
            app.PP.Items = {'Lagrange'};
            app.input1.Value ='[321.0 322.8 324.2 325.0]';
            app.input2.Value = '[2.50651 2.50893 2.51081 2.51188]';
            app.labelss.Text = "GTHQ";
            app.saiso.Value = '323.5';
            app.label1.Visible = 'on';
            app.label2.Visible = 'on';
            app.input1.Visible = 'on';
            app.input2.Visible = 'on';
            app.labelkq2.Visible = 'on';
            app.KQ2.Visible = 'on';
            app.phanlya.Visible = 'off';
            app.phanlyb.Visible = 'off';
            app.saiso.Visible = 'on';
            app.labelkq.Visible = 'on';
            app.labelsll.Visible = 'off';
            app.labelb.Visible = 'off';
            app.labela.Visible = 'off';
            app.labelss.Visible = 'on';
            app.KQ.Visible = 'on';
            app.solanlap.Visible = 'off';
            app.UIAxes.Visible = 'on';
            cla(app.UIAxes, 'reset');
        end

        % Button pushed function: but1112
        function but1112ButtonPushed(app, event)
            app.but1112.Text = '6.2/Tr.220';
            app.but8.Text = 'Chương 8';
            app.but9.Text = 'Chương 9';
            app.but10.Text = 'Chương 10';
            app.PP.Items = {'Taylor'};
            app.input1.Value ='[0.98 1.00 1.02]';
            app.input2.Value = '[0.7739332 0.7651977 0.7563321]';
            app.labelss.Text = "GTCT";
            app.saiso.Value = '1';
            app.label1.Visible = 'on';
            app.label2.Visible = 'on';
            app.input1.Visible = 'on';
            app.input2.Visible = 'on';
            app.labelkq2.Visible = 'off';
            app.KQ2.Visible = 'off';
            app.phanlya.Visible = 'off';
            app.phanlyb.Visible = 'off';
            app.saiso.Visible = 'on';
            app.labelkq.Visible = 'on';
            app.labelsll.Visible = 'off';
            app.labelb.Visible = 'off';
            app.labela.Visible = 'off';
            app.labelss.Visible = 'on';
            app.KQ.Visible = 'on';
            app.solanlap.Visible = 'off';
            app.UIAxes.Visible = 'on';
            cla(app.UIAxes, 'reset');
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            switch app.PP.Value
                case 'Dây cung'
                    fx = app.input1.Value;
                    f = str2func(['@(x)', fx]);
                    a = str2num(app.phanlya.Value);
                    b = str2num(app.phanlyb.Value);
                    ss = str2num(app.saiso.Value);
                    [c,n]= PP_Day_Cung(fx, a, b, ss);
                    app.solanlap.Value = num2str(n);
                    app.KQ.Value = num2str(c);
                    t = a:1/1000:b;
                    y1 = arrayfun(f,t);
                    plot(app.UIAxes, t, y1);
                case 'Tiếp tuyến'
                    fx = app.input1.Value;
                    f = str2func(['@(x)', fx]);
                    a = str2num(app.phanlya.Value);
                    b = str2num(app.phanlyb.Value);
                    ss = str2num(app.saiso.Value);
                    [x1,n] = PP_Tiep_Tuyen(fx, a, b, ss);
                    app.solanlap.Value = num2str(n);
                    app.KQ.Value = num2str(x1);
                    t = a:1/1000:b;
                    y1 = arrayfun(f,t);
                    plot(app.UIAxes, t, y1);
                case 'Lặp đơn'
                    fx = app.input1.Value;
                    fp = app.input2.Value;
                    f = str2func(['@(x)', fx]);
                    a = str2num(app.phanlya.Value);
                    b = str2num(app.phanlyb.Value);
                    ss = str2num(app.saiso.Value);
                    [d,n]= PP_Lap_Don(fx, fp, a, b, ss);
                    app.solanlap.Value = num2str(n);
                    app.KQ.Value = num2str(d);
                    t = a:1/1000:b;
                    y1 = arrayfun(f,t);
                    plot(app.UIAxes, t, y1);
            end
            
            if strcmp(app.PP.Value, 'GaussJordan')
                A = str2num(app.input1.Value);
                b = str2num(app.input2.Value);
                x = PP_Gauss_Jordan(A, b);
                x = sprintf('[%.4f; %.4f; %.4f; %.4f]', x);
                app.KQ.Value = x;  
            end
            
            if  strcmp(app.PP.Value, 'Lagrange')
                xa = str2num(app.input1.Value);
                ya = str2num(app.input2.Value);
                x = str2num(app.saiso.Value);
                result = PP_Lagrange(xa, ya, x);
                app.KQ.Value = num2str(result);
                xPlot = linspace(min(xa), max(xa), 100);
                yPlot = PP_NoiSuy_Lagrange(xa, ya, xPlot);
                plot(app.UIAxes, xPlot, yPlot);
                hold(app.UIAxes, 'on');
                p = polyfit(xa, ya, length(xa) - 1);
                app.KQ2.Value = poly2str(p, '.x');
                plot(app.UIAxes,xa,ya,'ro');
            end
            
            if  strcmp(app.PP.Value, 'Taylor')
                x = str2num(app.input1.Value);
                y = str2num(app.input2.Value);
                point = str2num(app.saiso.Value);
                dt = TaylorSeries(x,y,point);
                app.KQ.Value = num2str(dt);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'avatar.png';
            app.title = uilabel(app.UIFigure);
            app.title.Text = {'ĐỒ ÁN CUỐI HỌC KỲ'; 'Môn học: PHƯƠNG PHÁP TÍNH'; 'Giảng viên: Thầy Nguyễn Xuân Vinh'};
            app.idstudent = uilabel(app.UIFigure);
            app.idstudent.Text = {'Full Name: Nguyễn Phước Bảo'; 'ID Student: 21207123'; 'Class: 21DTV_CLC3'};
            app.but8 = uibutton(app.UIFigure, 'push');
            app.but8.ButtonPushedFcn = createCallbackFcn(app, @but8ButtonPushed, true);
            app.but8.Text = 'Chương 8';
            app.but9 = uibutton(app.UIFigure, 'push');
            app.but9.ButtonPushedFcn = createCallbackFcn(app, @but9ButtonPushed, true);
            app.but9.Text = 'Chương 9';
            app.but10 = uibutton(app.UIFigure, 'push');
            app.but10.ButtonPushedFcn = createCallbackFcn(app, @but10ButtonPushed, true);
            app.but10.Text = 'Chương 10';
            app.but1112 = uibutton(app.UIFigure, 'push');
            app.but1112.ButtonPushedFcn = createCallbackFcn(app, @but1112ButtonPushed, true);
            app.but1112.Text = 'Chương 11&12';
            app.input1 = uieditfield(app.UIFigure, 'text');
            app.input2 = uieditfield(app.UIFigure, 'text');
            app.label1 = uilabel(app.UIFigure);
            app.label1.Text = 'Dữ liệu 1';
            app.label2 = uilabel(app.UIFigure);
            app.label2.Text = 'Dữ liệu 2';
            app.phanlya = uieditfield(app.UIFigure, 'text');
            app.phanlyb = uieditfield(app.UIFigure, 'text');
            app.saiso = uieditfield(app.UIFigure, 'text');
            app.labela = uilabel(app.UIFigure);
            app.labela.Text = 'a';
            app.labelb = uilabel(app.UIFigure);
            app.labelb.Text = 'b';
            app.labelss = uilabel(app.UIFigure);
            app.labelss.Text = 'sai số';
            app.PHNGPHPDropDownLabel = uilabel(app.UIFigure);
            app.PHNGPHPDropDownLabel.Text = 'PHƯƠNG PHÁP';
            app.PP = uidropdown(app.UIFigure);
            app.PP.Items = {};
            app.PP.Value = {};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.solanlap = uieditfield(app.UIFigure, 'text');
            app.labelsll = uilabel(app.UIFigure);
            app.labelsll.Text = 'số lần lặp';
            app.labelkq = uilabel(app.UIFigure);
            app.labelkq.Text = 'KẾT QUẢ';
            app.KQ = uieditfield(app.UIFigure, 'text');
            app.labelkq2 = uilabel(app.UIFigure);
            app.labelkq2.Text = 'KẾT QUẢ';
            app.KQ2 = uieditfield(app.UIFigure, 'text');
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NguyenPhuocBao_21207123_bai_tap_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end